classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoplotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CommulativeButton          matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        CountryLabel               matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        RegionListBoxLabel         matlab.ui.control.Label
        RegionListBox              matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        DATA
        Cases
        CasesAvg
        Deaths
        DeathsAvg
        Dates
        Average
        avgOld
        Countries
        Regions
        ShowCases
        ShowDeaths
        ShowDaily
        ShowCommulative
        UItextOptions
        UItextCorD
    end
    
    methods (Access = private)
        
        function plotData(app)

            if app.ShowCases && app.ShowDeaths
                cla(app.UIAxes);
                yyaxis(app.UIAxes,'right');
                app.UIAxes.YAxis(2).Visible = 'on';
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes, app.Dates, app.CasesAvg);
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes, app.Dates, app.DeathsAvg,"Color",[1 0 0]);
            elseif app.ShowCases
                cla(app.UIAxes);
                yyaxis(app.UIAxes,'right');
                app.UIAxes.YAxis(2).Visible = 'off';
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes, app.Dates, app.CasesAvg);
            elseif app.ShowDeaths
                cla(app.UIAxes);
                yyaxis(app.UIAxes,'right');
                app.UIAxes.YAxis(2).Visible = 'off';
                yyaxis(app.UIAxes,'left');
                plot(app.UIAxes, app.Dates, app.DeathsAvg,"Color",[1 0 0]);
            end
            
            UItext(app);
        end
        
        function computeAverage(app)
            app.CasesAvg = movmean(app.Cases, app.Average);
            app.DeathsAvg = movmean(app.Deaths, app.Average);
            app.Average
        end
        
        function CountriesList(app)
            dataLength = length(app.DATA.Countries);
            app.Countries = strings;
            app.Countries(1) = "Global";
            for i = 1:dataLength
                app.Countries(i+1) = app.DATA.Countries{i}.Region;
            end
            app.CountryListBox.Items = app.Countries;
        end
        
        %%fill regions list
        function RegoinsList(app)
            
            dataLength = length(app.DATA.Countries);
            app.Regions = strings;
            app.Regions(1) = "All";
            if app.CountryListBox.Value == "Global"
                app.RegionListBox.Items = app.Regions;
                return;
            end
            
            for i = 1:dataLength
                if app.DATA.Countries{i}.Region == app.CountryListBox.Value
                    subeRegionLength = length(app.DATA.Countries{i}.SubRegion);
                    for j = 1:subeRegionLength
                        app.Regions(j + 1) = app.DATA.Countries{i}.SubRegion{j}.Region;
                    end
                    app.RegionListBox.Items = app.Regions;
                    return;
                end
            end
            
        end
        function CommulativeData(app)
            
            %%global data setting
            if string(app.CountryListBox.Value) == "Global"
            app.Cases = app.DATA.Cases;
            app.Deaths = app.DATA.Deaths;
            app.Dates = app.DATA.Dates;
            
            %%Countries data
            else
                CountryListLength = length(app.Countries);
                for i = 2:CountryListLength
                    if string(app.CountryListBox.Value) == app.Countries(i)
                        if string(app.RegionListBox.Value) == "All" %%Countries data
                            app.Cases = app.DATA.Countries{i}.Cases;
                            app.Deaths = app.DATA.Countries{i}.Deaths;
                        else
                            subeRegionLength = length(app.Regions);
                            for j = 1:subeRegionLength
                                if string(app.RegionListBox.Value) == app.Regions(j) %%Region data
                                    app.Cases = app.DATA.Countries{i -1}.SubRegion{j -1}.Cases;
                                    app.Deaths = app.DATA.Countries{i -1}.SubRegion{j -1}.Deaths;
                                end
                            end
                        end
                    end
                end
            end
            
        end
        
        function DailyData(app)
            %%global data setting
            if string(app.CountryListBox.Value) == "Global"
            app.Cases = app.DATA.CasesDaily;
            app.Deaths = app.DATA.DeathsDaily;
            app.Dates = app.DATA.Dates;
            
            %%Countries data
            else
                CountryListLength = length(app.Countries);
                for i = 2:CountryListLength
                    if string(app.CountryListBox.Value) == app.Countries(i)
                        if string(app.RegionListBox.Value) == "All" %%Countries data
                            app.Cases = app.DATA.Countries{i}.CasesDaily;
                            app.Deaths = app.DATA.Countries{i}.DeathsDaily;
                        else
                            subeRegionLength = length(app.Regions);
                            for j = 1:subeRegionLength
                                if string(app.RegionListBox.Value) == app.Regions(j) %%Region data
                                    app.Cases = app.DATA.Countries{i -1}.SubRegion{j -1}.CasesDaily;
                                    app.Deaths = app.DATA.Countries{i -1}.SubRegion{j -1}.DeathsDaily;
                                end
                            end
                        end
                    end
                end
            end
            
        end
        
        function DataConfig(app)
            
            if app.CommulativeButton.Value
                CommulativeData(app);
            elseif app.DailyButton.Value
                DailyData(app);
            end  
            
        end
        
        %%UI text update
        function UItext(app)
            
            if app.CommulativeButton.Value
                app.UItextOptions = "Commulative ";
            elseif app.DailyButton.Value
                app.UItextOptions = "Daily ";
            end
            
            if app.BothButton.Value
                app.UItextCorD = "Cases & Deaths ";
            elseif app.CasesButton.Value
                app.UItextCorD = "Cases ";
            elseif app.DeathsButton.Value
                app.UItextCorD = "Deaths ";
            end
            
            app.UIAxes.Title.String = "Number of " + string(app.UItextOptions) + string(app.UItextCorD);
            
            if string(app.CountryListBox.Value) == "Global"
                app.UIAxes.Title.String = app.UIAxes.Title.String + "Globally";
            elseif string(app.RegionListBox.Value) == "All"
                app.UIAxes.Title.String = app.UIAxes.Title.String + "in " + string(app.CountryListBox.Value);
            else 
                app.UIAxes.Title.String = app.UIAxes.Title.String + "in " + string(app.RegionListBox.Value);
            end
            
            if app.Average > 1
                app.UIAxes.Title.String = app.UIAxes.Title.String + " (" + int2str(app.Average) + "-days mean) ";
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%load data
            load covid_data.mat covid_data;
            app.DATA = Global(covid_data);
            app.Average = 1;
            
            %%data options
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowCommulative = app.CommulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            
            %%Drop down values
            CountriesList(app);
            RegoinsList(app);
            
            DataConfig(app);
            computeAverage(app);
            plotData(app);

        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            plotData(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ShowCommulative = app.CommulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            DataConfig(app);
            computeAverage(app);
            plotData(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            RegoinsList(app);
            app.RegionListBox.Items = app.Regions;
            DataConfig(app);
            computeAverage(app);
            plotData(app);
        end

        % Value changed function: RegionListBox
        function RegionListBoxValueChanged(app, event)
            DataConfig(app);
            computeAverage(app);
            plotData(app);
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.Average = floor(event.Value);
            computeAverage(app);
            plotData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Months')
            ylabel(app.UIAxes, 'Cases')
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 5 10 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CommulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CommulativeButton.Text = 'Commulative';
            app.CommulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.RegionListBoxLabel = uilabel(app.UIFigure);
            app.RegionListBoxLabel.Text = 'Region:';
            app.RegionListBox = uilistbox(app.UIFigure);
            app.RegionListBox.ValueChangedFcn = createCallbackFcn(app, @RegionListBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end